classdef mini_gland_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MiniGlandUIFigure               matlab.ui.Figure
        MiniGlandSimulationLabel_2      matlab.ui.control.Label
        ParotidGlandSimulationLabel     matlab.ui.control.Label
        AboutButton                     matlab.ui.control.Button
        UserManualButton                matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        EditFieldiAE_G_A                matlab.ui.control.NumericEditField
        anionexchangerdensityLabel      matlab.ui.control.Label
        EditFieldiG_P_Cl                matlab.ui.control.NumericEditField
        ClpermeabilityLabel_2           matlab.ui.control.Label
        EditFieldiG_Na_B                matlab.ui.control.NumericEditField
        NapermeabilityLabel             matlab.ui.control.Label
        EditFieldiNHE_G_B               matlab.ui.control.NumericEditField
        NHEcotransporterdensityLabel_2  matlab.ui.control.Label
        EditFieldiNBC_G_B               matlab.ui.control.NumericEditField
        NBCcotransporterdensityLabel_2  matlab.ui.control.Label
        EditFieldiAE_G_B                matlab.ui.control.NumericEditField
        anionexchangerdensityLabel_2    matlab.ui.control.Label
        EditFieldiNKA_alpha_B           matlab.ui.control.NumericEditField
        NaKATPasedensityLabel           matlab.ui.control.Label
        EditFieldiG_Cl_B                matlab.ui.control.NumericEditField
        ClpermeabilityLabel             matlab.ui.control.Label
        EditFieldiL_A                   matlab.ui.control.NumericEditField
        waterpermeabilityLabel          matlab.ui.control.Label
        EditFieldiG_CFTR                matlab.ui.control.NumericEditField
        CFTRpermeabilityLabel           matlab.ui.control.Label
        EditFieldiG_ENaC                matlab.ui.control.NumericEditField
        ENaCpermeabilityLabel           matlab.ui.control.Label
        DuctSimulationLabel             matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        MyParmsInputDropDown            matlab.ui.control.DropDown
        parametersfileLabel             matlab.ui.control.Label
        parametersfilelabelEditField    matlab.ui.control.EditField
        parametersfilelabelEditFieldLabel  matlab.ui.control.Label
        PlotsInputDropDown              matlab.ui.control.DropDown
        ductsimulationfileLabel         matlab.ui.control.Label
        resultsfilelabelEditField_3     matlab.ui.control.EditField
        resultsfilelabelEditField_3Label  matlab.ui.control.Label
        DuctInputDropDown               matlab.ui.control.DropDown
        acinussimulationfileLabel       matlab.ui.control.Label
        intercalatedstriatedLabel       matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        TightjunctionalLabel            matlab.ui.control.Label
        EditFieldsNHE_G_B               matlab.ui.control.NumericEditField
        molseccm2Label_4                matlab.ui.control.Label
        cmsLabel_7                      matlab.ui.control.Label
        singlecellCheckBox              matlab.ui.control.CheckBox
        LoadParmsButton                 matlab.ui.control.Button
        SaveParmsButton                 matlab.ui.control.Button
        volumeconcentrationsCheckBox    matlab.ui.control.CheckBox
        fluxesCheckBox                  matlab.ui.control.CheckBox
        GeneratePlotsButton             matlab.ui.control.Button
        EditFieldsG_P_Cl                matlab.ui.control.NumericEditField
        RunSimulationButtonDuct         matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        BasolateralLabel                matlab.ui.control.Label
        ApicalLabel                     matlab.ui.control.Label
        EditFieldsG_CFTR                matlab.ui.control.NumericEditField
        molseccm2Label_3                matlab.ui.control.Label
        molcm2Label_5                   matlab.ui.control.Label
        EditFieldsNBC_G_B               matlab.ui.control.NumericEditField
        EditFieldsAE_G_B                matlab.ui.control.NumericEditField
        molcm2Label_7                   matlab.ui.control.Label
        EditFieldsAE_G_A                matlab.ui.control.NumericEditField
        cmsLabel_8                      matlab.ui.control.Label
        molcm2Label                     matlab.ui.control.Label
        cmsLabel_6                      matlab.ui.control.Label
        cmsLabel_5                      matlab.ui.control.Label
        cmsLabel_2                      matlab.ui.control.Label
        cmsLabel                        matlab.ui.control.Label
        EditFieldsL_A                   matlab.ui.control.NumericEditField
        EditFieldsNKA_alpha_B           matlab.ui.control.NumericEditField
        EditFieldsG_Cl_B                matlab.ui.control.NumericEditField
        EditFieldsG_Na_B                matlab.ui.control.NumericEditField
        EditFieldsG_ENaC                matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        TightjunctionalLabel_2          matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        BasolateralLabel_2              matlab.ui.control.Label
        EditFieldbNaK                   matlab.ui.control.NumericEditField
        NaKATPasedensityEditFieldLabel_2  matlab.ui.control.Label
        amolum2Label_2                  matlab.ui.control.Label
        ApicalLabel_2                   matlab.ui.control.Label
        MyParmsInputDropDown_2          matlab.ui.control.DropDown
        parametersfileLabel_2           matlab.ui.control.Label
        parametersfilelabelEditField_2  matlab.ui.control.EditField
        parametersfilelabelEditField_2Label  matlab.ui.control.Label
        EditFieldLt                     matlab.ui.control.NumericEditField
        waterpermeabilityLabel_4        matlab.ui.control.Label
        EditFieldLb                     matlab.ui.control.NumericEditField
        waterpermeabilityLabel_2        matlab.ui.control.Label
        EditFieldLa                     matlab.ui.control.NumericEditField
        waterpermeabilityLabel_3        matlab.ui.control.Label
        EditFieldGK                     matlab.ui.control.NumericEditField
        maxconductanceofCaKCLabel       matlab.ui.control.Label
        EditFieldGCl                    matlab.ui.control.NumericEditField
        maxconductanceofCaCCLabel       matlab.ui.control.Label
        EditFieldaNaK                   matlab.ui.control.NumericEditField
        NaKATPasedensityEditFieldLabel  matlab.ui.control.Label
        EditFieldVPLC                   matlab.ui.control.NumericEditField
        maxphospholipaseCLabel          matlab.ui.control.Label
        uMsLabel                        matlab.ui.control.Label
        um3mMsLabel_3                   matlab.ui.control.Label
        um3mMsLabel_2                   matlab.ui.control.Label
        um3mMsLabel                     matlab.ui.control.Label
        fSLabel_2                       matlab.ui.control.Label
        fSLabel                         matlab.ui.control.Label
        amolum2Label                    matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        LoadParmsButton_2               matlab.ui.control.Button
        SaveParmsButton_2               matlab.ui.control.Button
        Label_9                         matlab.ui.control.Label
        AcinusSimulationLabel           matlab.ui.control.Label
        PlotsInputDropDown_2            matlab.ui.control.DropDown
        acinussimulationfileLabel_3     matlab.ui.control.Label
        resultsfilelabelEditField_2     matlab.ui.control.EditField
        resultsfilelabelEditField_2Label  matlab.ui.control.Label
        cellvolumeconcentrationsCheckBox  matlab.ui.control.CheckBox
        primarysalivaCheckBox           matlab.ui.control.CheckBox
        GeneratePlotsButton_2           matlab.ui.control.Button
        C14Button                       matlab.ui.control.StateButton
        C13Button                       matlab.ui.control.StateButton
        C12Button                       matlab.ui.control.StateButton
        C11Button                       matlab.ui.control.StateButton
        C10Button                       matlab.ui.control.StateButton
        C9Button                        matlab.ui.control.StateButton
        C8Button                        matlab.ui.control.StateButton
        C7Button                        matlab.ui.control.StateButton
        C6Button                        matlab.ui.control.StateButton
        C5Button                        matlab.ui.control.StateButton
        C4Button                        matlab.ui.control.StateButton
        C3Button                        matlab.ui.control.StateButton
        C2Button                        matlab.ui.control.StateButton
        C1Button                        matlab.ui.control.StateButton
        cellstosimulateLabel            matlab.ui.control.Label
        RunSimulationButtonAcinus       matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        AcinusFileVisListBox            matlab.ui.control.ListBox
        acinussimulationfilesListBoxLabel  matlab.ui.control.Label
        DuctFileVisDropDown             matlab.ui.control.DropDown
        ductsimulationfileLabel_2       matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        SimulationResultsVisualisationLabel  matlab.ui.control.Label
        VisSimulationResultsButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        %ini_file = 'parms_ex-vivo.ini' % default simulation parameters file name
        ini_file_duct = 'parms_default.mat' % default simulation parameters file name
        file_duct = 'parms_default.mat';
        ini_file_acinus = 'parms_default.mat' % default simulation parameters file name
        file_acinus = 'parms_default.mat';
        deployed_app_path = '~/Desktop/MiniGland/' % Description
    end
    
    methods (Access = private)
        function cells = get_sim_cells(app) % NOTE: the app parameter is always prepended
            cells = [];
            if app.C1Button.Value == 1; cells = [cells 1]; end
            if app.C2Button.Value == 1; cells = [cells 2]; end
            if app.C3Button.Value == 1; cells = [cells 3]; end
            if app.C4Button.Value == 1; cells = [cells 4]; end
            if app.C5Button.Value == 1; cells = [cells 5]; end
            if app.C6Button.Value == 1; cells = [cells 6]; end
            if app.C7Button.Value == 1; cells = [cells 7]; end
            if app.C8Button.Value == 1; cells = [cells 8]; end
            if app.C9Button.Value == 1; cells = [cells 9]; end
            if app.C10Button.Value == 1; cells = [cells 10]; end
            if app.C11Button.Value == 1; cells = [cells 11]; end
            if app.C12Button.Value == 1; cells = [cells 12]; end
            if app.C13Button.Value == 1; cells = [cells 13]; end
            if app.C14Button.Value == 1; cells = [cells 14]; end
        end
        function par = get_gui_parms_acinus(app)  % NOTE: the app parameter is always prepended
            par.VPLC = app.EditFieldVPLC.Value;
            par.aNaK = app.EditFieldaNaK.Value;
            par.bNaK = app.EditFieldbNaK.Value;
            par.GCl = app.EditFieldGCl.Value;
            par.GK = app.EditFieldGK.Value;
            par.La = app.EditFieldLa.Value;
            par.Lb = app.EditFieldLb.Value;
            par.Lt = app.EditFieldLt.Value;
        end
        function set_gui_parms_acinus(app, p)  % NOTE: the app parameter is always prepended
            app.EditFieldVPLC.Value = p.VPLC;
            app.EditFieldaNaK.Value = p.aNaK;
            app.EditFieldbNaK.Value = p.bNaK;
            app.EditFieldGCl.Value = p.GCl;
            app.EditFieldGK.Value = p.GK;
            app.EditFieldLa.Value = p.La;
            app.EditFieldLb.Value = p.Lb;
            app.EditFieldLt.Value = p.Lt;
        end
        function set_gui_parms_ini_acinus(app)
            cd('acinus');
            load(app.ini_file_acinus, 'par');
            cd('..');
            app.set_gui_parms_acinus(par);
        end
        function set_parm_files_acinus(app)  % NOTE: the app parameter is always prepended
            files = {dir("acinus/parms_*.mat").name}; % my parameters input files
            if ~isempty(files) 
                app.MyParmsInputDropDown_2.Items = files;
                app.MyParmsInputDropDown_2.Value = app.file_acinus;
            %else
            %    app.MyParmsInputDropDown_2.Value = "- none -";
            end
        end
        function set_plots_input_acinus(app)  % NOTE: the app parameter is always prepended
            files = {dir("acinus/result_*.mat").name}; % duct result files
            if ~isempty(files) 
                app.PlotsInputDropDown_2.Items = files;
                app.PlotsInputDropDown_2.Value = files(1);
                app.AcinusFileVisListBox.Items = files;
                app.AcinusFileVisListBox.Value = files(1);
            end
        end

        %function set_gui_parms(app, p_i, p_s, p_c)  % NOTE: the app parameter is always prepended
        function set_gui_parms(app, p_i, p_s)  % NOTE: the app parameter is always prepended
            % intercalated apical
            app.EditFieldiAE_G_A.Value = p_i.ae_g_a;
            %app.EditFieldiG_CaCC.Value = p_i.g_cacc;
            app.EditFieldiG_CFTR.Value = p_i.g_cftr;
            app.EditFieldiG_ENaC.Value = p_i.g_enac;
            %app.EditFieldiG_BK.Value = p_i.g_bk;
            %app.EditFieldiNBC_G_A.Value = p_i.nbc_g_a;
            %app.EditFieldiNHE_G_A.Value = p_i.nhe_g_a;
            app.EditFieldiL_A.Value = p_i.l_a;

            % intercaled basal 
            app.EditFieldiAE_G_B.Value = p_i.ae_g_b;
            app.EditFieldiG_Cl_B.Value = p_i.g_cl_b;
            %app.EditFieldiG_K_B.Value = p_i.g_k_b;
            app.EditFieldiG_Na_B.Value = p_i.g_na_b;
            app.EditFieldiNKA_alpha_B.Value = p_i.nka_alpha_b;
            app.EditFieldiNBC_G_B.Value = p_i.nbc_g_b;
            app.EditFieldiNHE_G_B.Value = p_i.nhe_g_b;
            %app.EditFieldiL_B.Value = p_i.l_b;

            % intercaled tight-junctional
            app.EditFieldiG_P_Cl.Value = p_i.g_p_cl;
            %app.EditFieldiG_P_K.Value = p_i.g_p_k;
            %app.EditFieldiG_P_Na.Value = p_i.g_p_na;

            % striated apical          
            app.EditFieldsAE_G_A.Value = p_s.ae_g_a;
            %app.EditFieldsG_CaCC.Value = p_s.g_cacc;
            app.EditFieldsG_CFTR.Value = p_s.g_cftr;
            app.EditFieldsG_ENaC.Value = p_s.g_enac;
            %app.EditFieldsG_BK.Value = p_s.g_bk;
            %app.EditFieldsNBC_G_A.Value = p_s.nbc_g_a;
            %pp.EditFieldsNHE_G_A.Value = p_s.nhe_g_a;
            app.EditFieldsL_A.Value = p_s.l_a;

            % striated basal
            app.EditFieldsAE_G_B.Value = p_s.ae_g_b;
            app.EditFieldsG_Cl_B.Value = p_s.g_cl_b;
            %app.EditFieldsG_K_B.Value = p_s.g_k_b;
            app.EditFieldsG_Na_B.Value = p_s.g_na_b;
            app.EditFieldsNKA_alpha_B.Value = p_s.nka_alpha_b;
            app.EditFieldsNBC_G_B.Value = p_s.nbc_g_b;
            app.EditFieldsNHE_G_B.Value = p_s.nhe_g_b;
            %app.EditFieldsL_B.Value = p_s.l_b;

            % striated tight-junctional
            app.EditFieldsG_P_Cl.Value = p_s.g_p_cl;
            %app.EditFieldsG_P_K.Value = p_s.g_p_k;
            %app.EditFieldsG_P_Na.Value = p_s.g_p_na;
        end

        function set_gui_parms_ini(app)
            cd('duct');
            load(app.ini_file_duct, 'parms');
            cd('..');
            %app.set_gui_parms(INI.intercalated, INI.striated, INI.duct_common);
            %app.set_gui_parms(INI.intercalated, INI.striated);
            app.set_gui_parms(parms.p_i, parms.p_s);
        end

        function parms = get_gui_parms(app)  % NOTE: the app parameter is always prepended
            % intercalated apical
            p_i.ae_g_a = app.EditFieldiAE_G_A.Value;
            %p_i.g_cacc = app.EditFieldiG_CaCC.Value;
            p_i.g_cftr = app.EditFieldiG_CFTR.Value;
            p_i.g_enac = app.EditFieldiG_ENaC.Value;
            %%%%p_i.g_bk = app.EditFieldiG_BK.Value;
            %%%%p_i.nbc_g_a = app.EditFieldiNBC_G_A.Value;
            %%%%p_i.nhe_g_a = app.EditFieldiNHE_G_A.Value;
            p_i.l_a = app.EditFieldiL_A.Value;

            % intercaled basal 
            p_i.ae_g_b = app.EditFieldiAE_G_B.Value;
            p_i.g_cl_b = app.EditFieldiG_Cl_B.Value;
            %%%%p_i.g_k_b = app.EditFieldiG_K_B.Value;
            p_i.g_na_b = app.EditFieldiG_Na_B.Value;
            p_i.nka_alpha_b = app.EditFieldiNKA_alpha_B.Value;
            p_i.nbc_g_b = app.EditFieldiNBC_G_B.Value;
            p_i.nhe_g_b = app.EditFieldiNHE_G_B.Value;
            %%%%p_i.l_b = app.EditFieldiL_B.Value;

            % intercaled tight-junctional
            p_i.g_p_cl = app.EditFieldiG_P_Cl.Value;
            %%%%p_i.g_p_k = app.EditFieldiG_P_K.Value;
            %%%%p_i.g_p_na = app.EditFieldiG_P_Na.Value;

            % striated apical          
            p_s.ae_g_a = app.EditFieldsAE_G_A.Value;
            %p_s.g_cacc = app.EditFieldsG_CaCC.Value;
            p_s.g_cftr = app.EditFieldsG_CFTR.Value;
            p_s.g_enac = app.EditFieldsG_ENaC.Value;
            %%%%p_s.g_bk = app.EditFieldsG_BK.Value;
            %%%%p_s.nbc_g_a = app.EditFieldsNBC_G_A.Value;
            %%%%p_s.nhe_g_a = app.EditFieldsNHE_G_A.Value;
            p_s.l_a = app.EditFieldsL_A.Value;

            % striated basal
            p_s.ae_g_b = app.EditFieldsAE_G_B.Value;
            p_s.g_cl_b = app.EditFieldsG_Cl_B.Value;
            %%%%p_s.g_k_b = app.EditFieldsG_K_B.Value;
            p_s.g_na_b = app.EditFieldsG_Na_B.Value;
            p_s.nka_alpha_b = app.EditFieldsNKA_alpha_B.Value;
            p_s.nbc_g_b = app.EditFieldsNBC_G_B.Value;
            p_s.nhe_g_b = app.EditFieldsNHE_G_B.Value;
            %%%%p_s.l_b = app.EditFieldsL_B.Value;
            
            % striated tight-junctional
            p_s.g_p_cl = app.EditFieldsG_P_Cl.Value;
            %%%%p_s.g_p_k = app.EditFieldsG_P_K.Value;
            %%%%p_s.g_p_na = app.EditFieldsG_P_Na.Value;
            
            parms.p_i = p_i;
            parms.p_s = p_s;
            %parms.p_c = p_c;
        end
        function set_acinus_files(app)  % NOTE: the app parameter is always prepended
            files = {dir("acinus/result_*.mat").name}; % duct result files
            if ~isempty(files) 
                app.DuctInputDropDown.Items = files;
                app.DuctInputDropDown.Value = files(1);
                %app.DuctFileVisDropDown.Items = files;
                %app.DuctFileVisDropDown.Value = files(1);
            end
        end
        function set_plots_input(app)  % NOTE: the app parameter is always prepended
            files = {dir("duct/result_*.mat").name}; % duct result files
            if ~isempty(files) 
                app.PlotsInputDropDown.Items = files;
                app.PlotsInputDropDown.Value = files(1);
                app.DuctFileVisDropDown.Items = files;
                app.DuctFileVisDropDown.Value = files(1);
            end
        end
        function set_parm_files(app)  % NOTE: the app parameter is always prepended
            files = {dir("duct/parms_*.mat").name}; % my parameters input files
            if ~isempty(files) 
                app.MyParmsInputDropDown.Items = files;
                app.MyParmsInputDropDown.Value = app.file_duct;
            %else
            %    app.MyParmsInputDropDown.Value = "- none -";
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Launching MiniGland app...');
            app.set_gui_parms_ini_acinus();          
            app.set_parm_files_acinus();
            app.set_plots_input_acinus();
            app.set_acinus_files();
            app.set_gui_parms_ini();          
            app.set_parm_files();
            app.set_plots_input();
        end

        % Button pushed function: RunSimulationButtonAcinus
        function RunSimulationButtonAcinusPushed(app, event)
            disp('Running acinus simulation...');
            d = uiconfirm(app.MiniGlandUIFigure,"Acinus simulation runtime is 2-3 hours per cell with the default parameters. Note that other parameter settings could result in longer runtimes and even solver non-convergence.","Confirm run simulation","Icon","warning");
            if strcmp(d, 'Cancel')
                return;
            end
            t = tic;
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Running acinus simulation','Indeterminate','on');
            run_acinus(app.get_sim_cells(), app.get_gui_parms_acinus(), get(app.resultsfilelabelEditField_2,'Value'));
            app.set_plots_input_acinus();
            app.set_acinus_files();
            close(d);
            disp('Acinus simulation complete');
            uialert(app.MiniGlandUIFigure,{'Elapsed time: ';toc2hms(toc(t));pwd},'Acinus simulation complete','Icon','success');
        end

        % Button pushed function: RunSimulationButtonDuct
        function RunSimulationButtonDuctPushed(app, event)
            %if strcmp(get(app.DuctInputDropDown,'Value'), '- none -')
            %    return;
            %end
            disp('Running duct simulation...');
            d = uiconfirm(app.MiniGlandUIFigure,"Duct simulation runtime is 3-4 minutes with the default parameters. Note that other parameter settings could result in much longer runtimes and even solver non-convergence.","Confirm run simulation","Icon","warning");
            if strcmp(d, 'Cancel')
                return;
            end
            t = tic;
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Running duct simulation','Indeterminate','on');
            %run_duct(app.ini_file, app.get_gui_parms(), ...
            run_duct(app.get_gui_parms(), ...
                get(app.DuctInputDropDown,'Value'), get(app.resultsfilelabelEditField_3,'Value'));
            app.set_plots_input();
            close(d);
            disp('Duct simulation complete.');
            uialert(app.MiniGlandUIFigure,{'Elapsed time: ';toc2hms(toc(t));pwd},'Duct simulation complete','Icon','success');
        end

        % Close request function: MiniGlandUIFigure
        function MiniGlandUIFigureCloseRequest(app, event)
            delete(app)
            disp('Exiting MiniGland app');
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            if strcmp(get(app.PlotsInputDropDown,'Value'), '- none -')
                return;
            end
            disp('Generating duct plot(s)...');
            p.steadystateduct = app.volumeconcentrationsCheckBox.Value;
            p.steadystatefluxes = app.fluxesCheckBox.Value;
            p.singlecell = app.singlecellCheckBox.Value;

            plot_duct(app.PlotsInputDropDown.Value, p);
            disp('Plot(s) completed.');
        end

        % Button pushed function: SaveParmsButton
        function SaveParmsButtonPushed(app, event)
            app.file_duct = strcat("parms_",app.parametersfilelabelEditField.Value,".mat");
            parms = app.get_gui_parms;
            cd('duct');
            save(app.file_duct,"parms");
            cd('..');
            app.set_parm_files();
        end

        % Button pushed function: LoadParmsButton
        function LoadParmsButtonPushed(app, event)
            %if strcmp(get(app.MyParmsInputDropDown,'Value'), '- none -')
            %    return;
            %end
            load(strcat("duct/", get(app.MyParmsInputDropDown,'Value')), "parms");
            %app.set_gui_parms(parms.p_i, parms.p_s, parms.p_c);
            app.set_gui_parms(parms.p_i, parms.p_s);
        end

        % Button pushed function: VisSimulationResultsButton
        function VisSimulationResultsButtonPushed(app, event)
            if strcmp(get(app.DuctFileVisDropDown,'Value'), '- none -')
                return;
            end
            if strcmp(app.AcinusFileVisListBox.Value, '- none -')
                return;
            end

            disp('Converting acinus data...');
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Converting acinus data','Indeterminate','on');
            convert_data_acinus(app.AcinusFileVisListBox.Value);
            !mv acinus/_4Unity_acinus.bin ../MiniGlandVis
            close(d);
            
            disp('Converting duct data...');
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Converting duct data','Indeterminate','on');
            convert_data(get(app.DuctFileVisDropDown,'Value'));
            !mv duct/_4Unity_duct.bin ../MiniGlandVis
            close(d);

            disp('Initialising interactive animation...');
            d = uiprogressdlg(app.MiniGlandUIFigure,'Title','Initialising interactive animation','Indeterminate','on');
            cd("../MiniGlandVis");
            !mini_gland.app/Contents/MacOS/MiniGland &
            !osascript mini_gland.scpt
            %!test1.app/Contents/MacOS/applet 
            
            cd("../MiniGlandSim");
            close(d);
        end

        % Button pushed function: SaveParmsButton_2
        function SaveParmsButton_2Pushed(app, event)
            app.file_acinus = strcat("parms_",app.parametersfilelabelEditField_2.Value,".mat");
            par = app.get_gui_parms_acinus;
            cd('acinus');
            save(app.file_acinus, "par");
            cd('..');
            app.set_parm_files_acinus();
        end

        % Button pushed function: LoadParmsButton_2
        function LoadParmsButton_2Pushed(app, event)
            %if strcmp(get(app.MyParmsInputDropDown_2,'Value'), '- none -')
            %    return;
            %end
            load(strcat("acinus/", get(app.MyParmsInputDropDown_2,'Value')), "par");
            app.set_gui_parms_acinus(par);

        end

        % Button pushed function: GeneratePlotsButton_2
        function GeneratePlotsButton_2Pushed(app, event)
            if strcmp(get(app.PlotsInputDropDown_2,'Value'), '- none -')
                return;
            end
            disp('Generating acinus plot(s)...');
            p.calcium = app.cellvolumeconcentrationsCheckBox.Value;
            p.saliva = app.primarysalivaCheckBox.Value;

            plot_acinus(app.PlotsInputDropDown_2.Value, p);
            disp('Plot(s) completed.');

        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msg = "Written by John Rugis, Shan Su and James Sneyd at the Unversity of Auckland in collaboration with David Yule's laboratory at the the University of Rochester.";
            msg = msg + newline + "Source code available at https://github.com/jrugis/MiniGlandSim.git, https://github.com/jrugis/parotid_duct.git.";
            msg = msg + newline + "Licensed under GPLv3.";
            msg = msg + newline + "Funded in part by NIH grant xxxxxxxx.";
            uialert(app.MiniGlandUIFigure,msg,"About","Icon","info");
        end

        % Button pushed function: UserManualButton
        function UserManualButtonPushed(app, event)
            disp('Displaying the user manual.');
            !open MiniGland_User_Manual.pdf &
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MiniGlandUIFigure = uifigure('Visible', 'off');
            app.MiniGlandUIFigure.Name = 'MiniGland';
            app.MiniGlandUIFigure.CloseRequestFcn = createCallbackFcn(app, @MiniGlandUIFigureCloseRequest, true);
            app.Panel = uipanel(app.MiniGlandUIFigure);
            app.VisSimulationResultsButton = uibutton(app.Panel, 'push');
            app.VisSimulationResultsButton.ButtonPushedFcn = createCallbackFcn(app, @VisSimulationResultsButtonPushed, true);
            app.VisSimulationResultsButton.Text = 'Display Interactive Animation';
            app.SimulationResultsVisualisationLabel = uilabel(app.Panel);
            app.SimulationResultsVisualisationLabel.Text = 'Simulation Results Visualisation';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '________________________________________________________________________________________________________________________________';
            app.ductsimulationfileLabel_2 = uilabel(app.Panel);
            app.ductsimulationfileLabel_2.Text = 'duct simulation file';
            app.DuctFileVisDropDown = uidropdown(app.Panel);
            app.DuctFileVisDropDown.Items = {'- none -', ''};
            app.DuctFileVisDropDown.Value = '- none -';
            app.acinussimulationfilesListBoxLabel = uilabel(app.Panel);
            app.acinussimulationfilesListBoxLabel.Text = 'acinus simulation file(s)';
            app.AcinusFileVisListBox = uilistbox(app.Panel);
            app.AcinusFileVisListBox.Items = {'- none -'};
            app.AcinusFileVisListBox.Multiselect = 'on';
            app.AcinusFileVisListBox.Value = {'- none -'};
            app.Panel_2 = uipanel(app.MiniGlandUIFigure);
            app.RunSimulationButtonAcinus = uibutton(app.Panel_2, 'push');
            app.RunSimulationButtonAcinus.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonAcinusPushed, true);
            app.RunSimulationButtonAcinus.Text = 'Run Simulation';
            app.cellstosimulateLabel = uilabel(app.Panel_2);
            app.cellstosimulateLabel.Text = 'cell(s) to simulate';
            app.C1Button = uibutton(app.Panel_2, 'state');
            app.C1Button.Text = 'C1';
            app.C2Button = uibutton(app.Panel_2, 'state');
            app.C2Button.Text = 'C2';
            app.C3Button = uibutton(app.Panel_2, 'state');
            app.C3Button.Text = 'C3';
            app.C4Button = uibutton(app.Panel_2, 'state');
            app.C4Button.Text = 'C4';
            app.C5Button = uibutton(app.Panel_2, 'state');
            app.C5Button.Text = 'C5';
            app.C6Button = uibutton(app.Panel_2, 'state');
            app.C6Button.Text = 'C6';
            app.C7Button = uibutton(app.Panel_2, 'state');
            app.C7Button.Text = 'C7';
            app.C8Button = uibutton(app.Panel_2, 'state');
            app.C8Button.Text = 'C8';
            app.C9Button = uibutton(app.Panel_2, 'state');
            app.C9Button.Text = 'C9';
            app.C10Button = uibutton(app.Panel_2, 'state');
            app.C10Button.Text = 'C10';
            app.C11Button = uibutton(app.Panel_2, 'state');
            app.C11Button.Text = 'C11';
            app.C12Button = uibutton(app.Panel_2, 'state');
            app.C12Button.Text = 'C12';
            app.C13Button = uibutton(app.Panel_2, 'state');
            app.C13Button.Text = 'C13';
            app.C14Button = uibutton(app.Panel_2, 'state');
            app.C14Button.Text = 'C14';
            app.GeneratePlotsButton_2 = uibutton(app.Panel_2, 'push');
            app.GeneratePlotsButton_2.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButton_2Pushed, true);
            app.GeneratePlotsButton_2.Text = 'Generate Plots';
            app.primarysalivaCheckBox = uicheckbox(app.Panel_2);
            app.primarysalivaCheckBox.Text = 'primary saliva';
            app.primarysalivaCheckBox.Value = true;
            app.cellvolumeconcentrationsCheckBox = uicheckbox(app.Panel_2);
            app.cellvolumeconcentrationsCheckBox.Text = 'cell volume, concentrations';
            app.cellvolumeconcentrationsCheckBox.Value = true;
            app.resultsfilelabelEditField_2Label = uilabel(app.Panel_2);
            app.resultsfilelabelEditField_2Label.Text = 'results file label';
            app.resultsfilelabelEditField_2 = uieditfield(app.Panel_2, 'text');
            app.acinussimulationfileLabel_3 = uilabel(app.Panel_2);
            app.acinussimulationfileLabel_3.Text = 'acinus simulation file';
            app.PlotsInputDropDown_2 = uidropdown(app.Panel_2);
            app.PlotsInputDropDown_2.Items = {'- none -', ''};
            app.PlotsInputDropDown_2.Value = '- none -';
            app.AcinusSimulationLabel = uilabel(app.Panel_2);
            app.AcinusSimulationLabel.Text = 'Acinus Simulation';
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = '_____________________________________________________________________________________';
            app.SaveParmsButton_2 = uibutton(app.Panel_2, 'push');
            app.SaveParmsButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveParmsButton_2Pushed, true);
            app.SaveParmsButton_2.Text = 'Save';
            app.LoadParmsButton_2 = uibutton(app.Panel_2, 'push');
            app.LoadParmsButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadParmsButton_2Pushed, true);
            app.LoadParmsButton_2.Text = 'Load';
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '_____________________________________________________________________________________';
            app.amolum2Label = uilabel(app.Panel_2);
            app.amolum2Label.Text = 'amol/um^2';
            app.fSLabel = uilabel(app.Panel_2);
            app.fSLabel.Text = 'fS';
            app.fSLabel_2 = uilabel(app.Panel_2);
            app.fSLabel_2.Text = 'fS';
            app.um3mMsLabel = uilabel(app.Panel_2);
            app.um3mMsLabel.Text = 'um^3/mM/s';
            app.um3mMsLabel_2 = uilabel(app.Panel_2);
            app.um3mMsLabel_2.Text = 'um^3/mM/s';
            app.um3mMsLabel_3 = uilabel(app.Panel_2);
            app.um3mMsLabel_3.Text = 'um^3/mM/s';
            app.uMsLabel = uilabel(app.Panel_2);
            app.uMsLabel.Text = 'uM/s';
            app.maxphospholipaseCLabel = uilabel(app.Panel_2);
            app.maxphospholipaseCLabel.Text = 'max phospholipase C';
            app.EditFieldVPLC = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldVPLC.Limits = [0 Inf];
            app.NaKATPasedensityEditFieldLabel = uilabel(app.Panel_2);
            app.NaKATPasedensityEditFieldLabel.Text = 'NaK ATPase density';
            app.EditFieldaNaK = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldaNaK.Limits = [0 Inf];
            app.maxconductanceofCaCCLabel = uilabel(app.Panel_2);
            app.maxconductanceofCaCCLabel.Text = 'max conductance of CaCC';
            app.EditFieldGCl = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldGCl.Limits = [0 Inf];
            app.maxconductanceofCaKCLabel = uilabel(app.Panel_2);
            app.maxconductanceofCaKCLabel.Text = 'max conductance of CaKC';
            app.EditFieldGK = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldGK.Limits = [0 Inf];
            app.waterpermeabilityLabel_3 = uilabel(app.Panel_2);
            app.waterpermeabilityLabel_3.Text = 'water permeability';
            app.EditFieldLa = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldLa.Limits = [0 Inf];
            app.waterpermeabilityLabel_2 = uilabel(app.Panel_2);
            app.waterpermeabilityLabel_2.Text = 'water permeability';
            app.EditFieldLb = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldLb.Limits = [0 Inf];
            app.waterpermeabilityLabel_4 = uilabel(app.Panel_2);
            app.waterpermeabilityLabel_4.Text = 'water permeability';
            app.EditFieldLt = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldLt.Limits = [0 Inf];
            app.parametersfilelabelEditField_2Label = uilabel(app.Panel_2);
            app.parametersfilelabelEditField_2Label.Text = 'parameters file label';
            app.parametersfilelabelEditField_2 = uieditfield(app.Panel_2, 'text');
            app.parametersfileLabel_2 = uilabel(app.Panel_2);
            app.parametersfileLabel_2.Text = 'parameters file';
            app.MyParmsInputDropDown_2 = uidropdown(app.Panel_2);
            app.MyParmsInputDropDown_2.Items = {};
            app.MyParmsInputDropDown_2.Value = {};
            app.ApicalLabel_2 = uilabel(app.Panel_2);
            app.ApicalLabel_2.Text = 'Apical';
            app.amolum2Label_2 = uilabel(app.Panel_2);
            app.amolum2Label_2.Text = 'amol/um^2';
            app.NaKATPasedensityEditFieldLabel_2 = uilabel(app.Panel_2);
            app.NaKATPasedensityEditFieldLabel_2.Text = 'NaK ATPase density';
            app.EditFieldbNaK = uieditfield(app.Panel_2, 'numeric');
            app.EditFieldbNaK.Limits = [0 Inf];
            app.BasolateralLabel_2 = uilabel(app.Panel_2);
            app.BasolateralLabel_2.Text = 'Basolateral';
            app.Label_14 = uilabel(app.Panel_2);
            app.Label_14.Text = '_____________________________________________________________________________________';
            app.TightjunctionalLabel_2 = uilabel(app.Panel_2);
            app.TightjunctionalLabel_2.Text = 'Tight-junctional ';
            app.Label_15 = uilabel(app.Panel_2);
            app.Label_15.Text = '_____________________________________________________________________________________';
            app.Label_16 = uilabel(app.Panel_2);
            app.Label_16.Text = '_____________________________________________________________________________________';
            app.Panel_3 = uipanel(app.MiniGlandUIFigure);
            app.EditFieldsG_ENaC = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsG_ENaC.Limits = [0 Inf];
            app.EditFieldsG_ENaC.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_Na_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsG_Na_B.Limits = [0 Inf];
            app.EditFieldsG_Na_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsG_Cl_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsG_Cl_B.Limits = [0 Inf];
            app.EditFieldsG_Cl_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNKA_alpha_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsNKA_alpha_B.Limits = [0 Inf];
            app.EditFieldsNKA_alpha_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsL_A = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsL_A.Limits = [0 Inf];
            app.EditFieldsL_A.ValueDisplayFormat = '%11.6g';
            app.cmsLabel = uilabel(app.Panel_3);
            app.cmsLabel.Text = 'cm/s';
            app.cmsLabel_2 = uilabel(app.Panel_3);
            app.cmsLabel_2.Text = 'cm/s';
            app.cmsLabel_5 = uilabel(app.Panel_3);
            app.cmsLabel_5.Text = 'cm/s';
            app.cmsLabel_6 = uilabel(app.Panel_3);
            app.cmsLabel_6.Text = 'cm/s';
            app.molcm2Label = uilabel(app.Panel_3);
            app.molcm2Label.Text = 'mol/cm^2';
            app.cmsLabel_8 = uilabel(app.Panel_3);
            app.cmsLabel_8.Text = 'cm/s';
            app.EditFieldsAE_G_A = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsAE_G_A.Limits = [0 Inf];
            app.EditFieldsAE_G_A.ValueDisplayFormat = '%11.6g';
            app.molcm2Label_7 = uilabel(app.Panel_3);
            app.molcm2Label_7.Text = 'mol/cm^2';
            app.EditFieldsAE_G_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsAE_G_B.Limits = [0 Inf];
            app.EditFieldsAE_G_B.ValueDisplayFormat = '%11.6g';
            app.EditFieldsNBC_G_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsNBC_G_B.Limits = [0 Inf];
            app.EditFieldsNBC_G_B.ValueDisplayFormat = '%11.6g';
            app.molcm2Label_5 = uilabel(app.Panel_3);
            app.molcm2Label_5.Text = 'mol/cm^2';
            app.molseccm2Label_3 = uilabel(app.Panel_3);
            app.molseccm2Label_3.Text = 'mol/sec/cm^2';
            app.EditFieldsG_CFTR = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsG_CFTR.Limits = [0 Inf];
            app.EditFieldsG_CFTR.ValueDisplayFormat = '%11.6g';
            app.ApicalLabel = uilabel(app.Panel_3);
            app.ApicalLabel.Text = 'Apical';
            app.BasolateralLabel = uilabel(app.Panel_3);
            app.BasolateralLabel.Text = 'Basolateral';
            app.Label_4 = uilabel(app.Panel_3);
            app.Label_4.Text = '_____________________________________________________________________________________';
            app.Label_5 = uilabel(app.Panel_3);
            app.Label_5.Text = '_____________________________________________________________________________________';
            app.RunSimulationButtonDuct = uibutton(app.Panel_3, 'push');
            app.RunSimulationButtonDuct.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonDuctPushed, true);
            app.RunSimulationButtonDuct.Text = 'Run Simulation';
            app.EditFieldsG_P_Cl = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsG_P_Cl.Limits = [0 Inf];
            app.EditFieldsG_P_Cl.ValueDisplayFormat = '%11.6g';
            app.GeneratePlotsButton = uibutton(app.Panel_3, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Text = 'Generate Plots';
            app.fluxesCheckBox = uicheckbox(app.Panel_3);
            app.fluxesCheckBox.Text = 'fluxes';
            app.fluxesCheckBox.Value = true;
            app.volumeconcentrationsCheckBox = uicheckbox(app.Panel_3);
            app.volumeconcentrationsCheckBox.Text = 'volume, concentrations';
            app.volumeconcentrationsCheckBox.Value = true;
            app.SaveParmsButton = uibutton(app.Panel_3, 'push');
            app.SaveParmsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParmsButtonPushed, true);
            app.SaveParmsButton.Text = 'Save';
            app.LoadParmsButton = uibutton(app.Panel_3, 'push');
            app.LoadParmsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParmsButtonPushed, true);
            app.LoadParmsButton.Text = 'Load';
            app.singlecellCheckBox = uicheckbox(app.Panel_3);
            app.singlecellCheckBox.Text = 'single cell';
            app.singlecellCheckBox.Value = true;
            app.cmsLabel_7 = uilabel(app.Panel_3);
            app.cmsLabel_7.Text = 'cm/s';
            app.molseccm2Label_4 = uilabel(app.Panel_3);
            app.molseccm2Label_4.Text = 'mol/sec/cm^2';
            app.EditFieldsNHE_G_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldsNHE_G_B.Limits = [0 Inf];
            app.EditFieldsNHE_G_B.ValueDisplayFormat = '%11.6g';
            app.TightjunctionalLabel = uilabel(app.Panel_3);
            app.TightjunctionalLabel.Text = 'Tight-junctional ';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '_____________________________________________________________________________________';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '_____________________________________________________________________________________';
            app.intercalatedstriatedLabel = uilabel(app.Panel_3);
            app.intercalatedstriatedLabel.Text = 'intercalated              striated';
            app.acinussimulationfileLabel = uilabel(app.Panel_3);
            app.acinussimulationfileLabel.Text = 'acinus simulation file';
            app.DuctInputDropDown = uidropdown(app.Panel_3);
            app.DuctInputDropDown.Items = {'- none -', ''};
            app.DuctInputDropDown.Value = '- none -';
            app.resultsfilelabelEditField_3Label = uilabel(app.Panel_3);
            app.resultsfilelabelEditField_3Label.Text = 'results file label';
            app.resultsfilelabelEditField_3 = uieditfield(app.Panel_3, 'text');
            app.ductsimulationfileLabel = uilabel(app.Panel_3);
            app.ductsimulationfileLabel.Text = 'duct simulation file';
            app.PlotsInputDropDown = uidropdown(app.Panel_3);
            app.PlotsInputDropDown.Items = {'- none -', ''};
            app.PlotsInputDropDown.Value = '- none -';
            app.parametersfilelabelEditFieldLabel = uilabel(app.Panel_3);
            app.parametersfilelabelEditFieldLabel.Text = 'parameters file label';
            app.parametersfilelabelEditField = uieditfield(app.Panel_3, 'text');
            app.parametersfileLabel = uilabel(app.Panel_3);
            app.parametersfileLabel.Text = 'parameters file';
            app.MyParmsInputDropDown = uidropdown(app.Panel_3);
            app.MyParmsInputDropDown.Items = {};
            app.MyParmsInputDropDown.Value = {};
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '_____________________________________________________________________________________';
            app.DuctSimulationLabel = uilabel(app.Panel_3);
            app.DuctSimulationLabel.Text = 'Duct Simulation';
            app.ENaCpermeabilityLabel = uilabel(app.Panel_3);
            app.ENaCpermeabilityLabel.Text = 'ENaC permeability';
            app.EditFieldiG_ENaC = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiG_ENaC.Limits = [0 Inf];
            app.EditFieldiG_ENaC.ValueDisplayFormat = '%11.6g';
            app.CFTRpermeabilityLabel = uilabel(app.Panel_3);
            app.CFTRpermeabilityLabel.Text = 'CFTR permeability';
            app.EditFieldiG_CFTR = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiG_CFTR.Limits = [0 Inf];
            app.EditFieldiG_CFTR.ValueDisplayFormat = '%11.6g';
            app.waterpermeabilityLabel = uilabel(app.Panel_3);
            app.waterpermeabilityLabel.Text = 'water permeability';
            app.EditFieldiL_A = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiL_A.Limits = [0 Inf];
            app.EditFieldiL_A.ValueDisplayFormat = '%11.6g';
            app.ClpermeabilityLabel = uilabel(app.Panel_3);
            app.ClpermeabilityLabel.Text = 'Cl- permeability';
            app.EditFieldiG_Cl_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiG_Cl_B.Limits = [0 Inf];
            app.EditFieldiG_Cl_B.ValueDisplayFormat = '%11.6g';
            app.NaKATPasedensityLabel = uilabel(app.Panel_3);
            app.NaKATPasedensityLabel.Text = 'NaK ATPase density';
            app.EditFieldiNKA_alpha_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiNKA_alpha_B.Limits = [0 Inf];
            app.EditFieldiNKA_alpha_B.ValueDisplayFormat = '%11.6g';
            app.anionexchangerdensityLabel_2 = uilabel(app.Panel_3);
            app.anionexchangerdensityLabel_2.Text = 'anion exchanger density';
            app.EditFieldiAE_G_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiAE_G_B.Limits = [0 Inf];
            app.EditFieldiAE_G_B.ValueDisplayFormat = '%11.6g';
            app.NBCcotransporterdensityLabel_2 = uilabel(app.Panel_3);
            app.NBCcotransporterdensityLabel_2.Text = 'NBC cotransporter density';
            app.EditFieldiNBC_G_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiNBC_G_B.Limits = [0 Inf];
            app.EditFieldiNBC_G_B.ValueDisplayFormat = '%11.6g';
            app.NHEcotransporterdensityLabel_2 = uilabel(app.Panel_3);
            app.NHEcotransporterdensityLabel_2.Text = 'NHE cotransporter density';
            app.EditFieldiNHE_G_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiNHE_G_B.Limits = [0 Inf];
            app.EditFieldiNHE_G_B.ValueDisplayFormat = '%11.6g';
            app.NapermeabilityLabel = uilabel(app.Panel_3);
            app.NapermeabilityLabel.Text = 'Na+ permeability';
            app.EditFieldiG_Na_B = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiG_Na_B.Limits = [0 Inf];
            app.EditFieldiG_Na_B.ValueDisplayFormat = '%11.6g';
            app.ClpermeabilityLabel_2 = uilabel(app.Panel_3);
            app.ClpermeabilityLabel_2.Text = 'Cl- permeability';
            app.EditFieldiG_P_Cl = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiG_P_Cl.Limits = [0 Inf];
            app.EditFieldiG_P_Cl.ValueDisplayFormat = '%11.6g';
            app.anionexchangerdensityLabel = uilabel(app.Panel_3);
            app.anionexchangerdensityLabel.Text = 'anion exchanger density';
            app.EditFieldiAE_G_A = uieditfield(app.Panel_3, 'numeric');
            app.EditFieldiAE_G_A.Limits = [0 Inf];
            app.EditFieldiAE_G_A.ValueDisplayFormat = '%11.6g';
            app.UserManualButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.UserManualButton.ButtonPushedFcn = createCallbackFcn(app, @UserManualButtonPushed, true);
            app.UserManualButton.Text = 'User Manual';
            app.AboutButton = uibutton(app.MiniGlandUIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.ParotidGlandSimulationLabel = uilabel(app.MiniGlandUIFigure);
            app.ParotidGlandSimulationLabel.Text = {'Parotid Gland Simulation'; ''};
            app.MiniGlandSimulationLabel_2 = uilabel(app.MiniGlandUIFigure);
            app.MiniGlandSimulationLabel_2.Text = {'University of Auckland'; 'University of Rochester'; ''};
            % Show the figure after all components are created
            app.MiniGlandUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mini_gland_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MiniGlandUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MiniGlandUIFigure)
        end
    end
end